classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Button_3     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_8     matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_10    matlab.ui.control.Button
        Button_11    matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        imageFiles           % 用于存储所有图像路径
        currentImageIndex    % 当前显示的图像索引
        processedImages      % 临时存储处理后的图像结果（灰度直方图和均衡化图）
        processedTitles      % 临时存储处理图像对应的标题
    end
     
    methods (Access = private)
        % 自定义直方图均衡化函数
        function equalizedImage = customHistogramEqualization(~, img, numLevels)
            % 确保图像为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end

            % 获取图像的宽度和高度
            [height, width] = size(img);

            % 统计图像直方图
            histogramValues = imhist(img, numLevels);

            % 计算累计频率
            cumulativeFrequency = zeros(numLevels, 1);
            cumulativeFrequency(1) = histogramValues(1);
            for i = 2:numLevels
                cumulativeFrequency(i) = cumulativeFrequency(i - 1) + histogramValues(i);
            end

            % 计算修正的灰度级
            adjustedGrayLevels = zeros(numLevels, 1);
            for i = 1:numLevels
                adjustedGrayLevels(i) = floor(cumulativeFrequency(i) * (numLevels - 1) / (width * height) + 0.5);
            end

            % 生成均衡化后的图像
            equalizedImage = zeros(height, width);
            for x = 1:width
                for y = 1:height
                    levelIndex = floor(double(img(y, x)) * (numLevels - 1) / 255) + 1;
                    equalizedImage(y, x) = adjustedGrayLevels(levelIndex) * (255 / (numLevels - 1));
                end
            end

            % 将均衡化后的图像转换回 uint8 类型
            equalizedImage = uint8(equalizedImage);
        end

        % 图像处理函数
        function equalizedImage = processImage(app, imagePath)
            % 读取图像
            img = imread(imagePath);

            % 调用自定义直方图均衡化函数
            numLevels = 256; % 设置灰度级数
            equalizedImage = app.customHistogramEqualization(img, numLevels);
        end

        % 显示当前处理的图像
        function displayImage(app)
            if isempty(app.processedImages)
                return;
            end

            % 获取当前要显示的数据
            imageToDisplay = app.processedImages{app.currentImageIndex};
            titleToDisplay = app.processedTitles{app.currentImageIndex};

            % 显示图像
            imshow(imageToDisplay, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = titleToDisplay;
        end
    end


    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;

            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end

            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end

            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};

            % 调用图像处理函数
            equalizedImage = app.processImage(imagePath);

            % 存储均衡化图像
            app.processedImages{end + 1} = equalizedImage;
            app.processedTitles{end + 1} = ['均衡化图像 - ' imagePath];

            % 显示第一张处理结果
            app.displayImage();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.currentImageIndex < numel(app.processedImages)
                app.currentImageIndex = app.currentImageIndex + 1;
                app.displayImage();
            else
                uialert(app.UIFigure, '已经是最后一张！', '提示');
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.currentImageIndex > 1
                app.currentImageIndex = app.currentImageIndex - 1;
                app.displayImage();
            else
                uialert(app.UIFigure, '已经是第一张！', '提示');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '图片缩放';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '图片旋转';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '展示区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输入转化的图片路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '灰度直方图';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '图片灰度化';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '图片加噪';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '空域滤波处理';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '频域滤波处理';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '图像边缘提取';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '图像目标提取';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '特征提取';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '上一张';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '下一张';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '转化尺度';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end